classdef AxyDatabaseTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AxyDatabasePathEditField       matlab.ui.control.EditField
        AxyDatabasePathEditFieldLabel  matlab.ui.control.Label
        databaseNameLabel              matlab.ui.control.Label
        lookingforLabel                matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ImportTab                      matlab.ui.container.Tab
        AddMD5HashButton               matlab.ui.control.Button
        fromKRSPEditField              matlab.ui.control.EditField
        fromKRSPEditFieldLabel         matlab.ui.control.Label
        FixSquirrelIDButton            matlab.ui.control.Button
        IssueRowsLabel                 matlab.ui.control.Label
        OpenIssuesLabel                matlab.ui.control.Label
        IssuesTable                    matlab.ui.control.Table
        CheckforErrorsButton           matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        UsingpatternLabel              matlab.ui.control.Label
        CompareButton                  matlab.ui.control.Button
        ExportDataPathEditField        matlab.ui.control.EditField
        ExportDataPathEditFieldLabel   matlab.ui.control.Label
        ArchiveDataPathEditField       matlab.ui.control.EditField
        ArchiveDataPathEditFieldLabel  matlab.ui.control.Label
        FixTimingTab                   matlab.ui.container.Tab
        StatusLabel                    matlab.ui.control.Label
        ConsoleTextArea                matlab.ui.control.TextArea
    end


    properties (Access = public)
        printCount = 0;
        T_AxyDB = [];
        databaseName = "AxyDatabase.xlsx";
        failureLabels = {};
    end

    methods (Access = public)

        function aprint(app,newText,varargin)
            if strcmp(newText,'') == 0
                app.ConsoleTextArea.Value = sprintf("%03d> %s\n%s",app.printCount,newText,strjoin(app.ConsoleTextArea.Value,'\n'));
                app.printCount = app.printCount + 1;
                if nargin > 2
                    app.ConsoleTextArea.BackgroundColor = 'r';
                else
                    app.ConsoleTextArea.BackgroundColor = 'k';
                end
            else
                app.ConsoleTextArea.Value = "";
            end
        end

        function status(app,isProcessing)
            if isProcessing
                app.StatusLabel.Text = 'Processing';
                app.StatusLabel.BackgroundColor = [0 1 0];
                pause(.2);
            else
                app.StatusLabel.Text = 'Idle';
                app.StatusLabel.BackgroundColor = [0.5 0.5 0.5];
            end
        end

        function importDatabase(app)
            dbFilepath = fullfile(app.AxyDatabasePathEditField.Value,app.databaseName);
            if isfile(dbFilepath)
                aprint(app,"importing AxyDatabase.xlsx");
                % override opts
                opts = detectImportOptions(dbFilepath);
                variableNames = opts.VariableNames;
                [variableTypes{1:numel(variableNames)}] = deal('string');
                [variableTypes{contains(variableNames,{'date','time'})}] = deal('datetime');
                variableTypes(contains(variableNames,{'squirrel_id','axy_fs'})) = {'double'};
                opts.VariableNames = variableNames;
                opts.VariableTypes = variableTypes;
                app.T_AxyDB = readtable(dbFilepath,opts);
                populateIssues(app);
            else
                aprint(app,"no AxyDatabase.xlsx",1);
            end
        end

        function populateIssues(app)
            app.FixSquirrelIDButton.Enable = false;
            app.AddMD5HashButton.Enable = false;
            issueTable = table;
            jj = 0;
            warning ('off','all');
            for ii = 1:size(app.T_AxyDB,1)
                [isValid,failureTypes] = isValidRow(app,ii);
                if ~isValid
                    jj = jj + 1;
                    issueTable.row(jj) = ii + 1; % excel row
                    issueTable.folder(jj) = app.T_AxyDB.folder(ii);
                    issueTable.filename(jj) = app.T_AxyDB.filename(ii);
                    issueTable.issues(jj) = {strjoin(app.failureLabels(failureTypes),', ')};
                    if ismember(3,failureTypes)
                        app.FixSquirrelIDButton.Enable = true;
                    end
                    if ismember(6,failureTypes)
                        app.AddMD5HashButton.Enable = true;
                    end
                end
            end
            warning ('on','all');
            app.IssuesTable.Data = issueTable;
            app.IssueRowsLabel.Text = sprintf("%i Rows",jj);
        end

        function [isValid,failureTypes] = isValidRow(app,ii)
            app.failureLabels{1} = 'file not found';
            app.failureLabels{2} = 'multiple files';
            app.failureLabels{3} = 'squirrel id';
            app.failureLabels{4} = 'Fs';
            app.failureLabels{5} = 'date/time formats';
            app.failureLabels{6} = 'needs md5 hash';

            failureTypes = [];
            isValid = true;
            n = 0;
            if ~ismissing(app.T_AxyDB.filename(ii))
                % find file
                [~,n] = findArchiveFile(app,ii);
                if n > 1
                    failureTypes = [failureTypes;2];
                    isValid = false;
                elseif n == 0
                    failureTypes = [failureTypes;1];
                    isValid = false;
                end
            else
                % missing is not a failure type, skip it altogether
            end
            if n ~= 0 % only do this if file exists
                if isnan(app.T_AxyDB.squirrel_id(ii))
                    failureTypes = [failureTypes;3];
                    isValid = false;
                end
                if isnan(app.T_AxyDB.axy_fs(ii))
                    failureTypes = [failureTypes;4];
                    isValid = false;
                end
                if ismissing(app.T_AxyDB.axy_d_fmt(ii)) || ismissing(app.T_AxyDB.axy_t_fmt(ii))
                    failureTypes = [failureTypes;5];
                    isValid = false;
                end
                if ismissing(app.T_AxyDB.md5_hash(ii))
                    failureTypes = [failureTypes;6];
                    isValid = false;
                end
            end
        end

        function didSave = saveAxyDB(app)
            didSave = false;
            % backup
            answer = questdlg(sprintf('Is %s closed?',app.databaseName),'CLOSE AXY DATABASE','Yes','No','No');
            if strcmp(answer,'Yes')
                copyfile(fullfile(app.AxyDatabasePathEditField.Value,app.databaseName),...
                    fullfile(app.AxyDatabasePathEditField.Value,app.databaseName+".backup"));
                aprint(app,"backed up database");
                % save new
                dateFmt = "dd/MM/yyyy";
                timeFmt = "HH:mm:ss";
                % can not get this to work dynamically
                app.T_AxyDB.power_on_date = string(app.T_AxyDB.power_on_date,dateFmt);
                app.T_AxyDB.deploy_date = string(app.T_AxyDB.deploy_date,dateFmt);
                app.T_AxyDB.removed_date = string(app.T_AxyDB.removed_date,dateFmt);
                app.T_AxyDB.power_off_date = string(app.T_AxyDB.power_off_date,dateFmt);
                app.T_AxyDB.date_signature = string(app.T_AxyDB.date_signature,dateFmt);
                app.T_AxyDB.power_on_time = string(app.T_AxyDB.power_on_time,timeFmt);
                app.T_AxyDB.deploy_time = string(app.T_AxyDB.deploy_time,timeFmt);
                app.T_AxyDB.removed_time = string(app.T_AxyDB.removed_time,timeFmt);
                app.T_AxyDB.power_off_time = string(app.T_AxyDB.power_off_time,timeFmt);
                app.T_AxyDB.shake1_start_time = string(app.T_AxyDB.shake1_start_time,timeFmt);
                app.T_AxyDB.shake1_stop_time = string(app.T_AxyDB.shake1_stop_time,timeFmt);
                app.T_AxyDB.shake2_start_time = string(app.T_AxyDB.shake2_start_time,timeFmt);
                app.T_AxyDB.shake2_stop_time = string(app.T_AxyDB.shake2_stop_time,timeFmt);
                writetable(app.T_AxyDB,fullfile(app.AxyDatabasePathEditField.Value,app.databaseName));
                aprint(app,"saved database");
                didSave = true;
            else
                aprint(app,"close axy database",true);
            end
        end
        
        function [files,n] = findArchiveFile(app,ii)
            [files,n] = dir3(fullfile(app.AxyDatabasePathEditField.Value,'Archive',app.T_AxyDB.folder(ii)),app.T_AxyDB.filename(ii));
        end
    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            aprint(app,"Hello, Squirrel");
            app.databaseNameLabel.Text = app.databaseName;
            status(app,false);
        end

        % Button pushed function: CheckforErrorsButton
        function CheckforErrorsButtonPushed(app, event)
            status(app,true);
            importDatabase(app);
            status(app,false);
        end

        % Button pushed function: FixSquirrelIDButton
        function FixSquirrelIDButtonPushed(app, event)
            status(app,true);
            aprint(app,'');
            krspFile = fullfile(app.AxyDatabasePathEditField.Value,app.fromKRSPEditField.Value);
            if isfile(krspFile)
                aprint(app,"reading krsp file");

                opts = detectImportOptions(krspFile);
                opts.VariableTypes(contains(opts.VariableNames,'tag')) = {deal('string')};
                T_KRSP = readtable(krspFile,opts);
                hasIssueCount = 0;
                fixedIssueCount = 0;
                for ii = 1:size(app.T_AxyDB,1)
                    if ~ismissing(app.T_AxyDB.filename(ii)) && isnan(app.T_AxyDB.squirrel_id(ii))
                        hasIssueCount = hasIssueCount + 1;
                        aprint(app,".");
                        if ismissing(app.T_AxyDB.tag_right(ii)) && ismissing(app.T_AxyDB.tag_left(ii))
                            aprint(app,sprintf("no tags row %i",ii+1));
                        else
                            matchIdx = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft) & strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                            if numel(matchIdx) == 1
                                aprint(app,sprintf("fixing row %i",ii+1));
                                app.T_AxyDB.squirrel_id(ii) = T_KRSP.id(matchIdx);
                                fixedIssueCount = fixedIssueCount + 1;
                            elseif numel(matchIdx) > 1
                                for jj = 1:numel(matchIdx)
                                    aprint(app,sprintf("may be: %i-%s-%s",...
                                        T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                end
                                aprint(app,sprintf("--MULT IDS ROW %i--",ii+1));
                            else
                                matchIdx = [];
                                if strcmp(app.T_AxyDB.tag_left(ii),'-') == 0
                                    tMatch = find(strcmp(app.T_AxyDB.tag_left(ii),T_KRSP.taglft));
                                    matchIdx = [matchIdx;tMatch]; %#ok<*AGROW>
                                end
                                if strcmp(app.T_AxyDB.tag_right(ii),'-') == 0
                                    tMatch = find(strcmp(app.T_AxyDB.tag_right(ii),T_KRSP.tagrt));
                                    matchIdx = [matchIdx;tMatch];
                                end
                                if isempty(matchIdx)
                                    aprint(app,sprintf("no match row %i",ii+1));
                                else
                                    for jj = 1:numel(matchIdx)
                                        aprint(app,sprintf("may be: %i-%s-%s",...
                                            T_KRSP.id(matchIdx(jj)),T_KRSP.gr{matchIdx(jj)},T_KRSP.reflo{matchIdx(jj)}));
                                    end
                                    aprint(app,sprintf("--PARTIAL TAG ROW %i--",ii+1));
                                end
                            end
                        end
                    end
                end
                % done fixing issues
                aprint(app,sprintf("fixed %i/%i squirrel ids",fixedIssueCount,hasIssueCount));
                if fixedIssueCount > 0 && saveAxyDB(app)
                    populateIssues(app);
                else
                    aprint(app,"no fixes, not saving");
                end
            else
                aprint(app,"no KRSP file",true);
            end
            status(app,false);
        end

        % Button pushed function: AddMD5HashButton
        function AddMD5HashButtonPushed(app, event)
            status(app,true);
            hashesUpdated = 0;
            if ~isunix
                aprint(app,"need unix OS",true);
            else
                for ii = 1:size(app.T_AxyDB,1)
                    [~,failureTypes] = isValidRow(app,ii);
                    [files,n] = findArchiveFile(app,ii);
                    if ismember(6,failureTypes) && n == 1
                        [status,cmdout] = system(sprintf('md5 "%s"',files.fullfile(1))); % unix
                        if status == 0
                            cmdParts = strsplit(strtrim(cmdout));
                            app.T_AxyDB.md5_hash(ii) = string(cmdParts(end));
                            hashesUpdated = hashesUpdated + 1;
                            aprint(app,"row %i hash complete",ii+1);
                        end
                    end
                end
                aprint(app,sprintf("%i hashes made",hashesUpdated));
            end
            if hashesUpdated > 0 && saveAxyDB(app)
                populateIssues(app);
            end
            status(app,false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.ConsoleTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.CheckforErrorsButton = uibutton(app.ImportTab, 'push');
            app.CheckforErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckforErrorsButtonPushed, true);
            app.CheckforErrorsButton.Text = 'Check for Errors';
            app.IssuesTable = uitable(app.ImportTab);
            app.IssuesTable.ColumnName = {'row'; 'folder'; 'filename'; 'issues'};
            app.IssuesTable.RowName = {};
            app.OpenIssuesLabel = uilabel(app.ImportTab);
            app.OpenIssuesLabel.Text = 'Open Issues:';
            app.IssueRowsLabel = uilabel(app.ImportTab);
            app.IssueRowsLabel.Text = '0 Rows';
            app.FixSquirrelIDButton = uibutton(app.ImportTab, 'push');
            app.FixSquirrelIDButton.ButtonPushedFcn = createCallbackFcn(app, @FixSquirrelIDButtonPushed, true);
            app.FixSquirrelIDButton.Enable = 'off';
            app.FixSquirrelIDButton.Text = 'Fix Squirrel ID';
            app.fromKRSPEditFieldLabel = uilabel(app.ImportTab);
            app.fromKRSPEditFieldLabel.Text = 'from';
            app.fromKRSPEditField = uieditfield(app.ImportTab, 'text');
            app.fromKRSPEditField.Value = '/Other/krsp_squirrel.csv';
            app.AddMD5HashButton = uibutton(app.ImportTab, 'push');
            app.AddMD5HashButton.ButtonPushedFcn = createCallbackFcn(app, @AddMD5HashButtonPushed, true);
            app.AddMD5HashButton.Enable = 'off';
            app.AddMD5HashButton.Text = 'Add MD5 Hash';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ArchiveDataPathEditFieldLabel = uilabel(app.ExportTab);
            app.ArchiveDataPathEditFieldLabel.Text = 'Archive Data Path';
            app.ArchiveDataPathEditField = uieditfield(app.ExportTab, 'text');
            app.ArchiveDataPathEditField.Value = '/Volumes/GAIDICASSD/KRSP/Axy Database/Archive';
            app.ExportDataPathEditFieldLabel = uilabel(app.ExportTab);
            app.ExportDataPathEditFieldLabel.Text = 'Export Data Path';
            app.ExportDataPathEditField = uieditfield(app.ExportTab, 'text');
            app.ExportDataPathEditField.Value = '/Volumes/GAIDICASSD/KRSP/Axy Database/Data';
            app.CompareButton = uibutton(app.ExportTab, 'push');
            app.CompareButton.Text = 'Compare';
            app.UsingpatternLabel = uilabel(app.ExportTab);
            app.UsingpatternLabel.Text = 'Using pattern: ';
            app.FixTimingTab = uitab(app.TabGroup);
            app.FixTimingTab.Title = 'Fix Timing';
            app.lookingforLabel = uilabel(app.UIFigure);
            app.lookingforLabel.Text = 'looking for:';
            app.databaseNameLabel = uilabel(app.UIFigure);
            app.databaseNameLabel.Text = 'Database';
            app.AxyDatabasePathEditFieldLabel = uilabel(app.UIFigure);
            app.AxyDatabasePathEditFieldLabel.Text = 'Axy Database Path';
            app.AxyDatabasePathEditField = uieditfield(app.UIFigure, 'text');
            app.AxyDatabasePathEditField.Value = '/Volumes/GAIDICASSD/KRSP/Axy Database';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AxyDatabaseTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end