classdef axySelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        EnterNestSliderLabel  matlab.ui.control.Label
        EnterNestSlider       matlab.ui.control.Slider
        AsleepSliderLabel     matlab.ui.control.Label
        AsleepSlider          matlab.ui.control.Slider
        AwakeSliderLabel      matlab.ui.control.Label
        AwakeSlider           matlab.ui.control.Slider
        ExitNestSliderLabel   matlab.ui.control.Label
        ExitNestSlider        matlab.ui.control.Slider
        SelectFileButton      matlab.ui.control.Button
        RandomButton          matlab.ui.control.Button
        PathEditFieldLabel    matlab.ui.control.Label
        PathEditField         matlab.ui.control.EditField
        NameEditFieldLabel    matlab.ui.control.Label
        NameEditField         matlab.ui.control.EditField
        DoneButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        EditField_2           matlab.ui.control.EditField
        Label_3               matlab.ui.control.Label
        EditField_3           matlab.ui.control.EditField
        EditField_4Label      matlab.ui.control.Label
        EditField_4           matlab.ui.control.EditField
        NAButton              matlab.ui.control.StateButton
        NAButton_2            matlab.ui.control.StateButton
        NAButton_3            matlab.ui.control.StateButton
        NAButton_4            matlab.ui.control.StateButton
        DatesListBoxLabel     matlab.ui.control.Label
        DatesListBox          matlab.ui.control.ListBox
        WriteEditFieldLabel   matlab.ui.control.Label
        WriteEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        lns
        odba
        temp
        dt
        thresh_lns
        colors = lines(6)
        odba_color
        temp_color
        vert_lw = 1.25
        thresh_vals
        ln_styles
        ln_colors
        allDates
        dtDays
        nRange
        useDay
    end
    
    methods (Access = private)
        
        function loadData(app)
            % init
            app.odba_color = app.colors(5,:);
            app.temp_color = app.colors(2,:);
            app.ln_styles = {':',':','-','-'};
            app.ln_colors = [0 0 0;1 0 0;1 0 0;0 0 0];
            app.UIAxes.Visible = true;
            app.DoneButton.Enable = true;
            app.EnterNestSlider.Enable = true;
            app.AsleepSlider.Enable = true;
            app.AwakeSlider.Enable = true;
            app.ExitNestSlider.Enable = true;
            app.NAButton.Enable = true;
            app.NAButton_2.Enable = true;
            app.NAButton_3.Enable = true;
            app.NAButton_4.Enable = true;
            app.WriteEditField.Enable = true;
            app.DatesListBox.Enable = true;
            app.WriteEditField.Value = strrep(app.NameEditField.Value,'.mat','_dn.csv');
            % load
            load(fullfile(app.PathEditField.Value,app.NameEditField.Value));
            app.dtDays = datetime(year(T.datetime),month(T.datetime),day(T.datetime));
            app.allDates = cellstr(datestr(unique(app.dtDays)));
            app.DatesListBox.Items = app.allDates;
            
            app.odba = T.odba;
            app.temp = T.temp;
            app.dt = T.datetime;
            
            for iDay = 1:numel(app.allDates)
                dayIds = find(app.dtDays == app.allDates{iDay});
                app.nRange(iDay,:) = [min(dayIds),max(dayIds)]; % real range
            end
            app.useDay = 1;
            app.changeDay();
        end
        
        function update_thresh(app,ln_id,x)
            yyaxis(app.UIAxes,"left");
            %             if isgraphics(app.thresh_lns(ln_id))
            delete(app.thresh_lns(ln_id));
            %             end
            app.thresh_vals(ln_id) = x;
            app.thresh_lns(ln_id) = plot(app.UIAxes,[x,x],[min(get(app.UIAxes,'ylim')),max(get(app.UIAxes,'ylim'))],...
                app.ln_styles{ln_id},'color',app.ln_colors(ln_id,:),'linewidth',app.vert_lw);
            %             app.fixLines();
        end
        
        function changeDay(app)
            app.UIAxes.Title.String = app.allDates{app.useDay};
            app.DatesListBox.Value = app.allDates{app.useDay};
            useLimits = [app.nRange(app.useDay,1),app.nRange(app.useDay,2)];
            app.AwakeSlider.Limits = useLimits;
            app.AwakeSlider.Value = useLimits(1); % CHANGE TO SAVED VALUED?
            app.ExitNestSlider.Limits = useLimits;
            app.ExitNestSlider.Value = useLimits(1); % CHANGE TO SAVED VALUED?
            app.EnterNestSlider.Limits = useLimits;
            app.EnterNestSlider.Value = useLimits(1); % CHANGE TO SAVED VALUED?
            app.AsleepSlider.Limits = useLimits;
            app.AsleepSlider.Value = useLimits(1); % CHANGE TO SAVED VALUED?
            app.plotDay();
        end
        
        function plotDay(app)
            dayRange = app.nRange(app.useDay,1):app.nRange(app.useDay,2);
            lw = 1;
            op = 0.4;
            app.lns = [];
            app.thresh_lns = [];
            yyaxis(app.UIAxes,"left");
            hold(app.UIAxes,"off");
            app.lns(1) = plot(app.UIAxes,dayRange,app.odba(dayRange),'-','linewidth',lw,'color',[app.odba_color,op]);
            set(app.UIAxes,'ycolor',app.odba_color);
            set(app.UIAxes,'ytick',ylim(app.UIAxes));
            set(app.UIAxes,'xlim',app.nRange(app.useDay,:));
            %             set(app.UIAxes,'xtick',xlim(app.UIAxes));
            hold(app.UIAxes,"on");
            
            yyaxis(app.UIAxes,"right");
            hold(app.UIAxes,"off");
            app.lns(2) = plot(app.UIAxes,dayRange,app.temp(dayRange),'-','linewidth',lw,'color',[app.temp_color,op]);
            hold(app.UIAxes,"on");
            set(app.UIAxes,'ycolor',app.temp_color);
            set(app.UIAxes,'ytick',ylim(app.UIAxes));
            ylabel(app.UIAxes,'temp (C)')
            % % % %             app.update_smooth(app.odba(app.nRange),"left",3,app.SmoothSlider.Value,app.odba_color);
            % % % %             app.update_smooth(app.temp(app.nRange),"right",4,app.SmoothSlider_2.Value,app.temp_color);
            app.thresh_vals(1:4) = 1:4;
            for ln_id = 1:4
                app.thresh_lns(ln_id) = plot(app.UIAxes,0);
            end
            app.update_thresh(1,app.AwakeSlider.Value);
            app.update_thresh(2,app.ExitNestSlider.Value);
            app.update_thresh(3,app.EnterNestSlider.Value);
            app.update_thresh(4,app.AsleepSlider.Value);
            % add ss
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            files = dir(fullfile(app.PathEditField.Value,'*.mat'));
            useFile = files(randi([1 size(files,1)])).name;
            app.NameEditField.Value = useFile;
            app.loadData();
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [file,path] = uigetfile(fullfile(app.PathEditField.Value,'*.mat'));
            app.PathEditField.Value = path;
            app.NameEditField.Value = file;
            app.loadData();
        end

        % Value changed function: EnterNestSlider
        function EnterNestSliderValueChanged(app, event)
            value = app.EnterNestSlider.Value;
            app.update_thresh(3,value);
        end

        % Value changed function: AsleepSlider
        function AsleepSliderValueChanged(app, event)
            value = app.AsleepSlider.Value;
            app.update_thresh(4,value);
        end

        % Value changed function: AwakeSlider
        function AwakeSliderValueChanged(app, event)
            value = app.AwakeSlider.Value;
            app.update_thresh(1,value);
        end

        % Value changed function: ExitNestSlider
        function ExitNestSliderValueChanged(app, event)
            value = app.ExitNestSlider.Value;
            app.update_thresh(2,value);
        end

        % Value changed function: DatesListBox
        function DatesListBoxValueChanged(app, event)
            value = app.DatesListBox.Value;
            app.useDay = find(strcmp(app.allDates,value));
            app.changeDay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'hour of day')
            ylabel(app.UIAxes, 'ODBA')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.EnterNestSliderLabel = uilabel(app.UIFigure);
            app.EnterNestSliderLabel.Enable = 'off';
            app.EnterNestSliderLabel.Text = 'Enter Nest';
            app.EnterNestSlider = uislider(app.UIFigure);
            app.EnterNestSlider.MajorTicks = [];
            app.EnterNestSlider.MajorTickLabels = {''};
            app.EnterNestSlider.ValueChangedFcn = createCallbackFcn(app, @EnterNestSliderValueChanged, true);
            app.EnterNestSlider.MinorTicks = [];
            app.EnterNestSlider.Enable = 'off';
            app.AsleepSliderLabel = uilabel(app.UIFigure);
            app.AsleepSliderLabel.Enable = 'off';
            app.AsleepSliderLabel.Text = 'Asleep';
            app.AsleepSlider = uislider(app.UIFigure);
            app.AsleepSlider.MajorTicks = [];
            app.AsleepSlider.MajorTickLabels = {''};
            app.AsleepSlider.ValueChangedFcn = createCallbackFcn(app, @AsleepSliderValueChanged, true);
            app.AsleepSlider.MinorTicks = [];
            app.AsleepSlider.Enable = 'off';
            app.AwakeSliderLabel = uilabel(app.UIFigure);
            app.AwakeSliderLabel.Enable = 'off';
            app.AwakeSliderLabel.Text = 'Awake';
            app.AwakeSlider = uislider(app.UIFigure);
            app.AwakeSlider.Limits = [0 10000];
            app.AwakeSlider.MajorTicks = [];
            app.AwakeSlider.MajorTickLabels = {''};
            app.AwakeSlider.ValueChangedFcn = createCallbackFcn(app, @AwakeSliderValueChanged, true);
            app.AwakeSlider.MinorTicks = [];
            app.AwakeSlider.Enable = 'off';
            app.ExitNestSliderLabel = uilabel(app.UIFigure);
            app.ExitNestSliderLabel.Enable = 'off';
            app.ExitNestSliderLabel.Text = 'Exit Nest';
            app.ExitNestSlider = uislider(app.UIFigure);
            app.ExitNestSlider.MajorTicks = [];
            app.ExitNestSlider.MajorTickLabels = {''};
            app.ExitNestSlider.ValueChangedFcn = createCallbackFcn(app, @ExitNestSliderValueChanged, true);
            app.ExitNestSlider.MinorTicks = [];
            app.ExitNestSlider.Enable = 'off';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.RandomButton = uibutton(app.UIFigure, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = '/Users/matt/Documents/Data/KRSP/CompressedAxy';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Editable = 'off';
            app.NAButton = uibutton(app.UIFigure, 'state');
            app.NAButton.Enable = 'off';
            app.NAButton.Text = 'N/A';
            app.NAButton_2 = uibutton(app.UIFigure, 'state');
            app.NAButton_2.Enable = 'off';
            app.NAButton_2.Text = 'N/A';
            app.NAButton_3 = uibutton(app.UIFigure, 'state');
            app.NAButton_3.Enable = 'off';
            app.NAButton_3.Text = 'N/A';
            app.NAButton_4 = uibutton(app.UIFigure, 'state');
            app.NAButton_4.Enable = 'off';
            app.NAButton_4.Text = 'N/A';
            app.DatesListBoxLabel = uilabel(app.UIFigure);
            app.DatesListBoxLabel.Enable = 'off';
            app.DatesListBoxLabel.Text = 'Dates';
            app.DatesListBox = uilistbox(app.UIFigure);
            app.DatesListBox.Items = {};
            app.DatesListBox.ValueChangedFcn = createCallbackFcn(app, @DatesListBoxValueChanged, true);
            app.DatesListBox.Enable = 'off';
            app.DatesListBox.Value = {};
            app.WriteEditFieldLabel = uilabel(app.UIFigure);
            app.WriteEditFieldLabel.Enable = 'off';
            app.WriteEditFieldLabel.Text = 'Write';
            app.WriteEditField = uieditfield(app.UIFigure, 'text');
            app.WriteEditField.Editable = 'off';
            app.WriteEditField.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axySelect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end